classdef appbase2mlapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Appbase2MlappUIFigure       matlab.ui.Figure
        WelcomeLabel                matlab.ui.control.Label
        SelectedFileEditFieldLabel  matlab.ui.control.Label
        SelectedFileEditField       matlab.ui.control.EditField
        BrowseFileButton            matlab.ui.control.Button
        ConvertButton               matlab.ui.control.Button
        TypeDropDownLabel           matlab.ui.control.Label
        TypeDropDown                matlab.ui.control.DropDown
        TabGroup                    matlab.ui.container.TabGroup
        PropertiesTab               matlab.ui.container.Tab
        PublicListBoxLabel          matlab.ui.control.Label
        PublicListBox               matlab.ui.control.ListBox
        Tree                        matlab.ui.container.Tree
        GroupButton                 matlab.ui.control.Button
        GroupingTab                 matlab.ui.container.Tab
    end

    
    properties (Access = private)
        filePath % The file to be converted
        designTime appdesigner.internal.model.DesignTimeProperties
        selectedProperties % Description
    end
    
    methods (Access = private)
        
        function results = getid(app)
            results = cell(4,1);
            letters ='A':'J';
            numbers = 1:9;
            
            % 8 char long, x2 chars per loop
            for c = 1:4
                str=letters(randi(numel(letters)));
                nm =numbers(randi(numel(numbers)));
                results{c} = strcat(char(str),num2str(nm));
            end
            results = char(horzcat(results{:}));
        end
        
        function cp(~, inFigNum,OutFigNum)
            % this program copies a figure to another figure
            % example: CloneFig(1,4) would copy Fig. 1 to Fig. 4
            % Matt Fetterman, 2009
            % pretty much taken from Matlab Technical solutions:
            % http://www.mathworks.com/support/solutions/en/data/1-1UTBOL/?solution=1-1UTBOL
            %             hf1=figure(inFigNum);
            %             hf2=figure(OutFigNum);
            %             clf;
            compCopy(inFigNum,OutFigNum);
            function compCopy(op, np)
                %COMPCOPY copies a figure object represented by "op" and its % descendants to another figure "np" preserving the same hierarchy.
                try
                    ch = get(op, 'children');
                catch
                    ch = [];
                end
                if ~isempty(ch)
                    
                    nh = copyobj(ch,np);
                    for k = 1:length(ch)
                        compCopy(ch(k),nh(k));
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.filePath = "";
            app.designTime = appdesigner.internal.model.DesignTimeProperties();
            app.selectedProperties = [];
        end

        % Button pushed function: BrowseFileButton
        function BrowseFileButtonPushed(app, event)
            
            initialUserPath = strcat(getenv("USERPROFILE"),'\Documents\MATLAB');
            
            [filename, filepath, ~] = uigetfile('../*.m', 'Select M file', initialUserPath);
            app.filePath = [filepath, filename];
            
            app.SelectedFileEditField.Value = app.filePath;
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            [filepath, filename] = fileparts(app.filePath);
            % The only that is missing from ".m" is "lapp"
            fileName = char(strcat(filename, '.mlapp'));
            fullPath = [filepath, '\', fileName];
            % May be there needs to be a relative path for the app to run?
            addpath(filepath);
            
            % Should be able to run in the first place
            [~, output1] = evalc(filename);
            drawnow();
            props = properties(output1);
            % Ignore the first one, because there is no need to group
            % uifigure with it is children
            app.PublicListBox.Items = props(2:end);
            
            %             for c = 1:length(props)
            %                 thisprop = props{c};
            %                 thisprop_value = output1.(thisprop);
            %                 if isa(thisprop_value, 'matlab.ui.Figure')
            %                     uiFigureOfCurrentApp = thisprop_value;
            %
            %                     %                     comp = appdesigner.internal.serialization.util.ComponentObjectToStructConverter(uiFigureOfCurrentApp);
            %                     %                     data = comp.getConvertedData()
            %                     %                     delete(comp);
            %                     %                     save("data1.mat", 'data')
            %                     uifig = appdesigner.internal.componentadapter.uicomponents.adapter.createUIFigure();
            %                     %                     uifig.Name = uiFigureOfCurrentApp.Name;
            %                     %                     uifig.Position = uiFigureOfCurrentApp.Position;
            %                     %                     copyobj(uiFigureOfCurrentApp.Children, uifig.Children);
            %                     app.cp(uiFigureOfCurrentApp, uifig);
            %                     get(uifig)
            %                     % This code produces the same result as the code below
            %                     %                     status = copyfile("./app1.mlapp", fullPath);
            %                     %
            %                     %                     if status
            %                     %                         defaultFileR = appdesigner.internal.serialization.FileReader('./app1.mlapp');
            %                     %                         defaultAppdesignerData = defaultFileR.readAppDesignerData;
            %                     %                         defaultAppdesignerData.components.UIFigure = uiFigureOfCurrentApp;
            %                     %
            %                     %                         newFileW = appdesigner.internal.serialization.FileWriter(fullPath);
            %                     %                         newFileW.writeAppDesignerData(defaultAppdesignerData);
            %                     %                     end
            %
            %                     if not(isempty(uiFigureOfCurrentApp))
            %                         fileW = appdesigner.internal.serialization.MLAPPSerializer(fullPath,uifig);
            %                         fileW.Groups = [];
            %                         fileW.save()
            %                     end
            %                     break;
            %                 end
            %             end
            %
        end

        % Value changed function: PublicListBox
        function PublicListBoxValueChanged(app, event)
            value = app.PublicListBox.Value;
            if size(value, 2) >=2
                app.selectedProperties = value;
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            event
            
        end

        % Button pushed function: GroupButton
        function GroupButtonPushed(app, event)
            n =size(app.Tree.Children,1);
            group = uitreenode(app.Tree,'Text',strcat('Group ', num2str(n)),'NodeData',[]);
            size(app.selectedProperties,2)
            for c = 1:size(app.selectedProperties,2)
                child = uitreenode(group,'Text',app.selectedProperties{c},'NodeData',[]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Appbase2MlappUIFigure = uifigure('Visible', 'off');
            app.Appbase2MlappUIFigure.Name = 'Appbase 2 Mlapp';
            app.WelcomeLabel = uilabel(app.Appbase2MlappUIFigure);
            app.WelcomeLabel.Text = 'Welcome';
            app.SelectedFileEditFieldLabel = uilabel(app.Appbase2MlappUIFigure);
            app.SelectedFileEditFieldLabel.Text = 'Selected File';
            app.SelectedFileEditField = uieditfield(app.Appbase2MlappUIFigure, 'text');
            app.SelectedFileEditField.Editable = 'off';
            app.BrowseFileButton = uibutton(app.Appbase2MlappUIFigure, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButtonPushed, true);
            app.BrowseFileButton.Text = 'Browse File...';
            app.ConvertButton = uibutton(app.Appbase2MlappUIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.TypeDropDownLabel = uilabel(app.Appbase2MlappUIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.Appbase2MlappUIFigure);
            app.TabGroup = uitabgroup(app.Appbase2MlappUIFigure);
            app.PropertiesTab = uitab(app.TabGroup);
            app.PropertiesTab.Title = 'Properties';
            app.PublicListBoxLabel = uilabel(app.PropertiesTab);
            app.PublicListBoxLabel.Text = 'Public';
            app.PublicListBox = uilistbox(app.PropertiesTab);
            app.PublicListBox.Items = {};
            app.PublicListBox.Multiselect = 'on';
            app.PublicListBox.ValueChangedFcn = createCallbackFcn(app, @PublicListBoxValueChanged, true);
            app.PublicListBox.Value = {};
            app.Tree = uitree(app.PropertiesTab);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.GroupButton = uibutton(app.PropertiesTab, 'push');
            app.GroupButton.ButtonPushedFcn = createCallbackFcn(app, @GroupButtonPushed, true);
            app.GroupButton.Text = 'Group >>>';
            app.GroupingTab = uitab(app.TabGroup);
            app.GroupingTab.Title = 'Grouping';
            % Show the figure after all components are created
            app.Appbase2MlappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appbase2mlapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Appbase2MlappUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Appbase2MlappUIFigure)
        end
    end
end